classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AlphaSlider                    matlab.ui.control.Slider
        AlphaSliderLabel               matlab.ui.control.Label
        FilterFunctionButtonGroup      matlab.ui.container.ButtonGroup
        GaussianButton                 matlab.ui.control.RadioButton
        ButterworthButton              matlab.ui.control.RadioButton
        IdealButton                    matlab.ui.control.RadioButton
        FilterOrderSlider              matlab.ui.control.Slider
        FilterOrderSliderLabel         matlab.ui.control.Label
        CutoffFrequencySlider          matlab.ui.control.Slider
        CutoffFrequencySliderLabel     matlab.ui.control.Label
        FilterToolsLabel               matlab.ui.control.Label
        ChooseMethodButtonGroup        matlab.ui.container.ButtonGroup
        RemoveNoiseButton              matlab.ui.control.RadioButton
        EnhanceFilterButton            matlab.ui.control.RadioButton
        HighPassFilterButton           matlab.ui.control.RadioButton
        LowPassFilterButton            matlab.ui.control.RadioButton
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        NoiseImageChoiceDropDown       matlab.ui.control.DropDown
        NoiseImageChoiceDropDownLabel  matlab.ui.control.Label
        APPLYButton                    matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        ImageFilteringLabel            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
           [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           figure(app.UIFigure);
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           app.UIAxes.Visible = 'on';
           app.UIAxes.XLim = [0 width(ImageFile)];
           app.UIAxes.YLim = [0 height(ImageFile)];
           imshow(ImageFile, 'Parent', app.UIAxes);
           app.Image.Visible = 'off';
           app.APPLYButton.Enable = 'on';
%            ChooseMethodButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Image.Visible = 'on';
            app.UIAxes.Visible = 'off';
            delete(app.UIAxes.Children);
            app.APPLYButton.Enable = 'off';
            
%             app.ChooseMethodButtonGroup.SelectedObject = app.LowPassFilterButton;
%             app.FilterFunctionButtonGroup.SelectedObject = app.IdealButton;
%             app.CutoffFrequencySlider.Value = 1;
%             app.AlphaSlider.Value = 1;
%             app.FilterOrderSlider.Value = 1;
%             ChooseMethodButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: ChooseMethodButtonGroup
        function ChooseMethodButtonGroupSelectionChanged(app, event)
            if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter" ||...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "Enhance Filter")
                app.FilterFunctionButtonGroup.Visible = 'on';
                app.CutoffFrequencySlider.Visible = 'on';
                app.CutoffFrequencySliderLabel.Visible ='on';
            else
                app.FilterFunctionButtonGroup.Visible = 'off';
                app.CutoffFrequencySlider.Visible = 'off';
                app.CutoffFrequencySliderLabel.Visible ='off';
            end
            if (~isempty(app.UIAxes.Children) && ...
                ~isempty(app.ChooseMethodButtonGroup.SelectedObject))
                if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                    app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter")
                    app.FilterFunctionButtonGroup.Visible = 'on';
                    if (~isempty(app.FilterFunctionButtonGroup.SelectedObject))
                        app.APPLYButton.Enable = 'on';
                    end
                else
                    app.APPLYButton.Enable = 'on';
                end
            end

            selectedFilter = app.FilterFunctionButtonGroup.SelectedObject;
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;

            RESETButtonPushed(app, event);

            if (selectedMethod.Text == "Remove Noise")
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
                app.AlphaSlider.Visible = 'off';
                app.AlphaSliderLabel.Visible = 'off';
                app.Image.Visible = 'off';
                app.NoiseImageChoiceDropDown.Visible = 'on';
                app.NoiseImageChoiceDropDownLabel.Visible = 'on';
                NoiseImageChoiceDropDownValueChanged(app, event);
            else
                app.NoiseImageChoiceDropDown.Visible = 'off';
                app.NoiseImageChoiceDropDownLabel.Visible = 'off';
                if (selectedMethod.Text == "Enhance Filter")
                    app.AlphaSlider.Visible = 'on';
                    app.AlphaSliderLabel.Visible = 'on';
                else
                    app.AlphaSlider.Visible = 'off';
                    app.AlphaSliderLabel.Visible = 'off';
                end
                if (selectedFilter.Text ~= "Butterworth")
                    app.FilterOrderSlider.Visible = 'off';
                    app.FilterOrderSliderLabel.Visible = 'off';
                else
                    app.FilterOrderSlider.Visible = 'on';
                    app.FilterOrderSliderLabel.Visible = 'on';
                end
            end
        end

        % Value changed function: CutoffFrequencySlider
        function CutoffFrequencySliderValueChanged(app, event)
            value = app.CutoffFrequencySlider.Value;
            
        end

        % Selection changed function: FilterFunctionButtonGroup
        function FilterFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterFunctionButtonGroup.SelectedObject;
            if (selectedButton.Text == "Butterworth")
                app.FilterOrderSlider.Visible = 'on';
                app.FilterOrderSliderLabel.Visible = 'on';
            else
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
            end
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;
            if (selectedMethod.Text == "Remove Noise")
                scriptMap = containers.Map(["res2/9.png", "res2/11.jpg", "res2/14.jpg"], ["t4_9png" "t4_11jpg" "t4_14jpg"]);
                disp(scriptMap(app.NoiseImageChoiceDropDown.Value));
                run(scriptMap(app.NoiseImageChoiceDropDown.Value));
            else
                img = app.UIAxes.Children.CData;
                filterType = app.FilterFunctionButtonGroup.SelectedObject.Text;
                cutOff = app.CutoffFrequencySlider.Value;
                filterOrd = app.FilterOrderSlider.Value;
                alpha = app.AlphaSlider.Value;
    
                disp(filterType);
                disp(cutOff);
         
                if (selectedMethod.Text == "Low Pass Filter")
                    LowPassFilter(img, filterType, cutOff, filterOrd);
                elseif (selectedMethod.Text == "High Pass Filter")
                    HighPassFilter(img, filterType, cutOff, filterOrd);
                elseif (selectedMethod.Text == "Enhance Filter")
                    res = HighBoost(img, alpha, filterType, cutOff, filterOrd);
                    figure,imshow(img);
                    figure,imshow(res);
                end
            end
        end

        % Value changed function: NoiseImageChoiceDropDown
        function NoiseImageChoiceDropDownValueChanged(app, event)
            fullname = app.NoiseImageChoiceDropDown.Value;
            figure(app.UIFigure);
            ImageFile = imread(fullname);
            app.UIAxes.Visible = 'on';
            app.UIAxes.XLim = [0 width(ImageFile)];
            app.UIAxes.YLim = [0 height(ImageFile)];
            imshow(ImageFile, 'Parent', app.UIAxes);
            app.Image.Visible = 'off';
            app.APPLYButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {548, 548};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChooseMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.ChooseMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseMethodButtonGroupSelectionChanged, true);
            app.ChooseMethodButtonGroup.Title = 'Choose Method';
            app.ChooseMethodButtonGroup.Tag = 'method';
            app.LowPassFilterButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.LowPassFilterButton.Text = 'Low Pass Filter';
            app.LowPassFilterButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.EnhanceFilterButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.EnhanceFilterButton.Text = 'Enhance Filter';
            app.RemoveNoiseButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.FilterToolsLabel = uilabel(app.LeftPanel);
            app.FilterToolsLabel.Text = 'Filter Tools';
            app.CutoffFrequencySliderLabel = uilabel(app.LeftPanel);
            app.CutoffFrequencySliderLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencySlider = uislider(app.LeftPanel);
            app.CutoffFrequencySlider.Limits = [1 250];
            app.CutoffFrequencySlider.MajorTicks = [1 50 100 150 200 250];
            app.CutoffFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.CutoffFrequencySlider.Tag = 'cutoff';
            app.CutoffFrequencySlider.Value = 1;
            app.FilterOrderSliderLabel = uilabel(app.LeftPanel);
            app.FilterOrderSliderLabel.Visible = 'off';
            app.FilterOrderSliderLabel.Text = 'Filter Order';
            app.FilterOrderSlider = uislider(app.LeftPanel);
            app.FilterOrderSlider.Limits = [1 5];
            app.FilterOrderSlider.MajorTicks = [1 2 3 4 5];
            app.FilterOrderSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.FilterOrderSlider.Tag = 'filter-order';
            app.FilterOrderSlider.Visible = 'off';
            app.FilterOrderSlider.Value = 1;
            app.FilterFunctionButtonGroup = uibuttongroup(app.LeftPanel);
            app.FilterFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterFunctionButtonGroupSelectionChanged, true);
            app.FilterFunctionButtonGroup.Title = 'Filter Function';
            app.FilterFunctionButtonGroup.Tag = 'filter-function';
            app.IdealButton = uiradiobutton(app.FilterFunctionButtonGroup);
            app.IdealButton.Text = 'Ideal';
            app.IdealButton.Value = true;
            app.ButterworthButton = uiradiobutton(app.FilterFunctionButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.GaussianButton = uiradiobutton(app.FilterFunctionButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.AlphaSliderLabel = uilabel(app.LeftPanel);
            app.AlphaSliderLabel.Visible = 'off';
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.LeftPanel);
            app.AlphaSlider.Limits = [1 5];
            app.AlphaSlider.MajorTicks = [1 2 3 4 5];
            app.AlphaSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.AlphaSlider.Tag = 'filter-order';
            app.AlphaSlider.Visible = 'off';
            app.AlphaSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ImageFilteringLabel = uilabel(app.GridLayout2);
            app.ImageFilteringLabel.Text = 'Image Filtering';
            app.RESETButton = uibutton(app.GridLayout2, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.APPLYButton = uibutton(app.GridLayout2, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Enable = 'off';
            app.APPLYButton.Text = 'APPLY';
            app.NoiseImageChoiceDropDownLabel = uilabel(app.GridLayout2);
            app.NoiseImageChoiceDropDownLabel.Visible = 'off';
            app.NoiseImageChoiceDropDownLabel.Text = 'Noise Image Choice:';
            app.NoiseImageChoiceDropDown = uidropdown(app.GridLayout2);
            app.NoiseImageChoiceDropDown.Items = {'res2/9.png', 'res2/11.jpg', 'res2/14.jpg'};
            app.NoiseImageChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseImageChoiceDropDownValueChanged, true);
            app.NoiseImageChoiceDropDown.Visible = 'off';
            app.NoiseImageChoiceDropDown.Value = 'res2/9.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end